classdef DynamicMasking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DynamicMaskingAppUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        Panel                      matlab.ui.container.Panel
        HelpButton                 matlab.ui.control.Button
        Back                       matlab.ui.control.Button
        NextKeyFrame_2             matlab.ui.control.Button
        NextKeyFrame               matlab.ui.control.Button
        Play                       matlab.ui.control.Button
        PrevKeyFrame               matlab.ui.control.Button
        SliderFrames               matlab.ui.control.Slider
        Label_3                    matlab.ui.control.Label
        FrameLabel                 matlab.ui.control.Label
        SaveButton                 matlab.ui.control.Button
        ColorMin                   matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        ColorMax                   matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        ColorLabel                 matlab.ui.control.Label
        ImageLabel                 matlab.ui.control.Label
        SliderImage                matlab.ui.control.Slider
        ColorbarImage              matlab.ui.control.Image
        LoadButton                 matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        ColorMenu                  matlab.ui.container.ContextMenu
        ParulaMenu                 matlab.ui.container.Menu
        HotMenu                    matlab.ui.container.Menu
        CoolMenu                   matlab.ui.container.Menu
        JetMenu                    matlab.ui.container.Menu
        GrayMenu                   matlab.ui.container.Menu
    end

    
    properties (Access = public)
        allImages; % stores all images loaded  [Rows x Columns x Frames x Images]

        allFiles; %Stores all image files
        filePath; %Stores the working file path
        ImageHeight; %Stores the image height
        ImageWidth; %Stores the image width
        FrameCount; %Number of frames in the image
        CurrentFrame=1; %Stores the current frame
        CurrentImage=1; %Stores the current frame
        
        colorbarMin=0; %colorbar minimum value
        colorbarMax=16384; %colorbar maximum value
        colormap=jet(256); %colormap used

        polygonX=[]; %polygon X coordinates [Vertex x KeyImage x Frame]
        polygonY=[]; %polygon Y coordinates
        polygonKeyImages=[];
        currentPolygon;

        SliderFramesMenu; %Menu container for slider frames
    end
    
    methods (Access = private)
        function updateColorbar(app)
            app.UIAxes.Colormap=app.colormap;
            app.UIAxes.CLim=[app.colorbarMin app.colorbarMax];
            app.ColorbarImage.ImageSource=reshape(flipud(app.colormap),size(app.colormap,1),1,size(app.colormap,2));
            app.ColorbarImage.ScaleMethod='stretch';
        end

        function resetPolygon(app)
            %Resets the polygon to the original image size
            H=app.ImageHeight;
            W=app.ImageWidth;
            Px=[0 W W 0]';
            Py=[0 0 H H]';
            app.polygonKeyImages=[1 length(app.allFiles)];
            app.polygonX=repmat(Px,1,length(app.polygonKeyImages),app.FrameCount);
            app.polygonY=repmat(Py,1,length(app.polygonKeyImages),app.FrameCount);

            drawPolygon(app);
        end
        
    end
    
    methods (Access = public) 

        function drawPolygon(app)
            %Shows a polygon in the screen that is interpolated given the
            %frame and the nearest keyframes
            Px=interp1(app.polygonKeyImages,app.polygonX(:,:,app.CurrentFrame)',app.CurrentImage);
            Py=interp1(app.polygonKeyImages,app.polygonY(:,:,app.CurrentFrame)',app.CurrentImage);
            
            delete(app.currentPolygon);
            app.currentPolygon=drawpolygon(app.UIAxes,'Position',[Px; Py]','Color','y');
            addlistener(app.currentPolygon,'ROIMoved',@(varargin)PolygonMoved(app,app.currentPolygon));
            addlistener(app.currentPolygon,'VertexAdded',@(varargin)PolygonVertexAdd(app,app.currentPolygon));
            addlistener(app.currentPolygon,'VertexDeleted',@(varargin)PolygonVertexDelete(app,app.currentPolygon));

        end

        function Iout=loadImage(app, i)
            %Reads multi-frame image from file and returns it
            [~,~,ext] = fileparts(app.allFiles(i));
            if strcmp(ext,'.im7')
                I=readimxfile(fullfile(app.filePath,app.allFiles{i}));
            else
                I=imread(fullfile(app.filePath,app.allFiles{i}));
            end

            if strcmp(ext,'.im7')
                %Images from DaVis
                for j=1:length(I.Frames)
                    I1=I.Frames{j}.Components{1}.Planes{1}';
                    I1=double(I1);
                    Iout(:,:,j)=I1;
                end
            else
                Iout(:,:,1)=double(I); %If the set is a series of regular images, then only one frame is considered
            end
        end

        function saveImage(app,I,fullpath)
            [~, ~, ext]=fileparts(fullpath);
            %Saves a multi-frame image at a given filepath
            if strcmp(ext,'.im7')
                %Images from DaVis                
                writeimxfile(I,fullpath);
            else
                %I=imread(fullfile(app.filePath,app.allFiles{i}));
            end


        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateColorbar(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            validExtensions={'*.im7';'*.jpg';'*.png';'*.jpeg'; '*.tif';'*.tiff'};
            [f,p]=uigetfile(validExtensions,'Please select the images to be opened','Multiselect','on');
            
            if ~iscell(f)
                if f==0
                    return; %No file selected
                end
                f={f}; %One file selected
            end

            %Checks if there are at least 2 files
            if length(f)<2
                msgbox('Please select at least two files!');
                return;
            end

            %Checks if file extension is valid
            invalidExtension=0;
            for i=1:length(f)
                [~,~,ext] = fileparts(f(i));
                if ~any(strcmp(erase(validExtensions,'*'),ext))
                    %Invalid extension detected
                    invalidExtension=1;
                    break;
                end
            end

            if invalidExtension
                msgbox('Extension of at least one of the files selected cannot be handled by the program!');
                return;
            else
                app.allFiles=f;
                app.filePath=p;
                
                %Loads the first image and sets variables
                I=app.loadImage( 1);
                app.ImageHeight=size(I,1);
                app.ImageWidth=size(I,2);
                app.FrameCount=size(I,3);

                %Resets the polygon in the screen
                resetPolygon(app);

                %Updates frames slider
                app.CurrentFrame=1;
                app.SliderFrames.Limits=[1 app.FrameCount+0.00001];   
                app.SliderFrames.Value=1;
                app.SliderFrames.MajorTicks=1:app.FrameCount;
                app.SliderFrames.MinorTicks=1:app.FrameCount;
                app.SliderFrames.MinorTicksMode='Manual';
                app.SliderFrames.MajorTicksMode='Manual';

                %Add menus to frame slider
                app.SliderFramesMenu=uicontextmenu(app.DynamicMaskingAppUIFigure);
                for i=1:app.FrameCount
                    m(i)=uimenu(app.SliderFramesMenu,'Label',['Copy Masks to Frame ' num2str(i)],'MenuSelectedFcn',@(varargin)CopyMasks(app, i));
                end
                app.SliderFrames.ContextMenu=app.SliderFramesMenu;

                %Updates images slider
                app.SliderImage.Limits=[1 length(f)+0.00001];
                app.SliderImage.Value=1;
                app.SliderImage.MajorTicks=round(linspace(1,length(f),min([6 length(f)])));
                app.SliderImage.MinorTicks=round(linspace(1,length(f),min([6 length(f)])));
                app.SliderImage.MinorTicksMode='Manual';
                app.SliderImage.MajorTicksMode='Manual';

                SliderValueChanging(app,app.SliderImage);
                
                %Resets the colorbar
                app.ColorMin.Value=0; app.colorbarMin=0;
                app.ColorMax.Value=16384; app.colorbarMax=16384;
                updateColorbar(app);

            end
        end

        % Callback function: SliderImage, SliderImage
        function SliderValueChanging(app, event)
            app.CurrentImage = round(app.SliderImage.Value);
            app.SliderImage.Value=app.CurrentImage;

            I=app.loadImage(app.CurrentImage);
            imagesc(I(:,:,app.CurrentFrame), 'Parent', app.UIAxes); 
            daspect(app.UIAxes,[1 1 1]);
            app.UIAxes.XColor='none';
            app.UIAxes.YColor='none';
            app.UIAxes.Color=app.GridLayout.BackgroundColor;
            app.UIAxes.Colormap=app.colormap;

            drawPolygon(app);

            app.ImageLabel.Text=['Image #' num2str(app.CurrentImage)];
        end

        % Callback function: SliderFrames, SliderFrames
        function Slider_2ValueChanged(app, event)
            app.CurrentFrame = round(app.SliderFrames.Value);
            app.SliderFrames.Value=app.CurrentFrame;

            I=app.loadImage(app.CurrentImage);
            imagesc(I(:,:,app.CurrentFrame), 'Parent', app.UIAxes); 
            daspect(app.UIAxes,[1 1 1]);
            app.UIAxes.XColor='none';
            app.UIAxes.YColor='none';
            app.UIAxes.Color=app.GridLayout.BackgroundColor;
            app.UIAxes.Colormap=app.colormap;
            
            drawPolygon(app);
        end

        % Value changed function: ColorMax
        function ColorMaxValueChanged(app, event)
            app.colorbarMin = app.ColorMin.Value;
            app.colorbarMax = app.ColorMax.Value;
            updateColorbar(app);
        end

        % Value changed function: ColorMin
        function ColorMinValueChanged(app, event)
            app.colorbarMin = app.ColorMin.Value;
            app.colorbarMax = app.ColorMax.Value;
            updateColorbar(app);            
        end

        % Menu selected function: ParulaMenu
        function ParulaMenuSelected(app, event)
            app.colormap=parula(256);
            updateColorbar(app);            
        end

        % Menu selected function: HotMenu
        function HotMenuSelected(app, event)
            app.colormap=hot(256);
            updateColorbar(app);     
        end

        % Menu selected function: CoolMenu
        function CoolMenuSelected(app, event)
            app.colormap=cool(256);
            updateColorbar(app);     
        end

        % Menu selected function: JetMenu
        function JetMenuSelected(app, event)
            app.colormap=jet(256);
            updateColorbar(app);     
        end

        % Menu selected function: GrayMenu
        function GrayMenuSelected(app, event)
            app.colormap=gray(256);
            updateColorbar(app);     
        end

        % Button pushed function: PrevKeyFrame
        function PrevKeyFrameButtonPushed(app, event)
            allPrevFrames=find(app.polygonKeyImages<app.CurrentImage);
            if ~isempty(allPrevFrames)
                app.CurrentImage = app.polygonKeyImages(allPrevFrames(end));
                app.SliderImage.Value=app.CurrentImage;                

                SliderValueChanging(app, event)
            end
        end

        % Button pushed function: NextKeyFrame
        function NextKeyFrameButtonPushed(app, event)
            allNextFrames=find(app.polygonKeyImages>app.CurrentImage);
            if ~isempty(allNextFrames)
                app.CurrentImage = app.polygonKeyImages(allNextFrames(1));
                app.SliderImage.Value=app.CurrentImage;                

                SliderValueChanging(app, event)
            end
        end

        % Button pushed function: NextKeyFrame_2
        function DeleteKeyframeMenuSelected(app, event)
            thisKeyFrame=find(app.polygonKeyImages==app.CurrentImage);            
            
            if ~isempty(thisKeyFrame)
                if (thisKeyFrame==1) || (thisKeyFrame==length(app.polygonKeyImages))
                    msgbox('You can''t delete the first and last keyframes!');
                    return;
                end

                app.polygonKeyImages(thisKeyFrame)=[];
                app.polygonX(:,thisKeyFrame,:)=[];
                app.polygonY(:,thisKeyFrame,:)=[];

                drawPolygon(app);
            else
                %Not a keyframe image
                msgbox('This is not a keyframe image. Use the keyframe buttons to navigate to the nearest keyframes!');
            end

        end

        % Button pushed function: Play
        function PlayButtonPushed(app, event)
            %Advances to next image
            if app.SliderImage.Value<length(app.allFiles)
                app.SliderImage.Value=app.SliderImage.Value+1;
                SliderValueChanging(app, event)
            end
        end

        % Button pushed function: Back
        function BackButtonPushed(app, event)
            %Returns to previous image
            if app.SliderImage.Value>1
                app.SliderImage.Value=app.SliderImage.Value-1;
                SliderValueChanging(app, event)
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %Saves all images
            wbar=uiprogressdlg(app.DynamicMaskingAppUIFigure,'Title','Saving',...
            'Message','Saving Images...');
            
            %Creates Mask Folder
            MaskPath=fullfile(app.filePath,'Mask');
            if ~exist(MaskPath,'dir')
                mkdir(MaskPath);
            else
                try
                    rmdir(MaskPath);
                    mkdir(MaskPath);
                catch
                    warning('It wasn''t possible to update the Masks directory due to access issues!')
                end
            end

            RefI=zeros(app.ImageHeight,app.ImageWidth); %Empty image for roipoly creation
            RefStruct=readimxfile(fullfile(app.filePath,app.allFiles{1}));
            for i=1:length(app.allFiles)
                %Creates a mask for every frame
                Iout=RefStruct;
                for j=1:app.FrameCount
                    Px=interp1(app.polygonKeyImages,app.polygonX(:,:,j)',i);
                    Py=interp1(app.polygonKeyImages,app.polygonY(:,:,j)',i);
                    I(:,:)=roipoly(RefI, Px, Py)*256;
                    Iout.Frames{j}.Components{1}.Planes{1}=uint16(I');
                end

                [~, N, ext]=fileparts(app.allFiles(i));
                iPath=fullfile(MaskPath,[N ext]);
                app.saveImage(Iout,iPath);

                wbar.Value=i/length(app.allFiles);
            end
                

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DynamicMaskingAppUIFigure = uifigure('Visible', 'off');
            app.DynamicMaskingAppUIFigure.Name = 'Dynamic Masking App';
            app.DynamicMaskingAppUIFigure.Icon = 'ProgramIcon.png';
            app.GridLayout = uigridlayout(app.DynamicMaskingAppUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Panel = uipanel(app.GridLayout);
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = 'folder.png';
            app.LoadButton.Text = 'Load';
            app.ColorbarImage = uiimage(app.Panel);
            app.ColorbarImage.Tooltip = {'Click to enhance the contrast of the image'};
            app.SliderImage = uislider(app.Panel);
            app.SliderImage.Limits = [0 1];
            app.SliderImage.MajorTicks = [];
            app.SliderImage.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.SliderImage.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.SliderImage.Tooltip = {'Drag to change the current image in the screen'};
            app.ImageLabel = uilabel(app.Panel);
            app.ImageLabel.Text = 'Image';
            app.ColorLabel = uilabel(app.Panel);
            app.ColorLabel.Text = 'Color';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.ColorMax = uieditfield(app.Panel, 'numeric');
            app.ColorMax.Limits = [0 Inf];
            app.ColorMax.ValueDisplayFormat = '%.0f';
            app.ColorMax.ValueChangedFcn = createCallbackFcn(app, @ColorMaxValueChanged, true);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '';
            app.ColorMin = uieditfield(app.Panel, 'numeric');
            app.ColorMin.Limits = [0 Inf];
            app.ColorMin.ValueDisplayFormat = '%.0f';
            app.ColorMin.ValueChangedFcn = createCallbackFcn(app, @ColorMinValueChanged, true);
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save.png';
            app.SaveButton.Text = {'Save'; ''};
            app.FrameLabel = uilabel(app.Panel);
            app.FrameLabel.Text = 'Frame #';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = {''; ''};
            app.SliderFrames = uislider(app.Panel);
            app.SliderFrames.Limits = [0 1];
            app.SliderFrames.MajorTicks = [];
            app.SliderFrames.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.SliderFrames.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.SliderFrames.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.SliderFrames.Tooltip = {'Slide to select which camera frame to make masks for. Right click to copy masks.'};
            app.PrevKeyFrame = uibutton(app.Panel, 'push');
            app.PrevKeyFrame.ButtonPushedFcn = createCallbackFcn(app, @PrevKeyFrameButtonPushed, true);
            app.PrevKeyFrame.Icon = 'PrevKeyFrame.png';
            app.PrevKeyFrame.Tooltip = {'Previous Keyframe'};
            app.PrevKeyFrame.Text = '';
            app.Play = uibutton(app.Panel, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.Play.Icon = 'Play.png';
            app.Play.Tooltip = {'Advances to next image'};
            app.Play.Text = '';
            app.NextKeyFrame = uibutton(app.Panel, 'push');
            app.NextKeyFrame.ButtonPushedFcn = createCallbackFcn(app, @NextKeyFrameButtonPushed, true);
            app.NextKeyFrame.Icon = 'NextKeyFrame.png';
            app.NextKeyFrame.Tooltip = {'Next Keyframe'};
            app.NextKeyFrame.Text = '';
            app.NextKeyFrame_2 = uibutton(app.Panel, 'push');
            app.NextKeyFrame_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteKeyframeMenuSelected, true);
            app.NextKeyFrame_2.Icon = 'DeleteKeyFrame.png';
            app.NextKeyFrame_2.Tooltip = {'Remove Keyframe'};
            app.NextKeyFrame_2.Text = '';
            app.Back = uibutton(app.Panel, 'push');
            app.Back.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.Back.Icon = 'Back.png';
            app.Back.Tooltip = {'Advances to next image'};
            app.Back.Text = '';
            app.HelpButton = uibutton(app.Panel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Icon = 'Info.png';
            app.HelpButton.Tooltip = {'Shows usage tips'};
            app.HelpButton.Text = 'Help';
            app.ColorMenu = uicontextmenu(app.DynamicMaskingAppUIFigure);
            app.ParulaMenu = uimenu(app.ColorMenu);
            app.ParulaMenu.MenuSelectedFcn = createCallbackFcn(app, @ParulaMenuSelected, true);
            app.ParulaMenu.Text = 'Parula';
            app.HotMenu = uimenu(app.ColorMenu);
            app.HotMenu.MenuSelectedFcn = createCallbackFcn(app, @HotMenuSelected, true);
            app.HotMenu.Text = 'Hot';
            app.CoolMenu = uimenu(app.ColorMenu);
            app.CoolMenu.MenuSelectedFcn = createCallbackFcn(app, @CoolMenuSelected, true);
            app.CoolMenu.Text = 'Cool';
            app.JetMenu = uimenu(app.ColorMenu);
            app.JetMenu.MenuSelectedFcn = createCallbackFcn(app, @JetMenuSelected, true);
            app.JetMenu.Text = 'Jet';
            app.GrayMenu = uimenu(app.ColorMenu);
            app.GrayMenu.MenuSelectedFcn = createCallbackFcn(app, @GrayMenuSelected, true);
            app.GrayMenu.Text = 'Gray';
            % Assign app.ColorMenu
            app.ColorbarImage.ContextMenu = app.ColorMenu;
            % Show the figure after all components are created
            app.DynamicMaskingAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DynamicMasking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DynamicMaskingAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DynamicMaskingAppUIFigure)
        end
    end
end